classdef KR160_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        KUKAKR160R1570NANOROBOTLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Mert_KARARLabel               matlab.ui.control.Label
        Mehmet_BARILabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        LoadRobotButton               matlab.ui.control.Button
        ControlPanelTab               matlab.ui.container.Tab
        PositionField                 matlab.ui.control.EditField
        POSITIONLabel                 matlab.ui.control.Label
        DEMOButton                    matlab.ui.control.Button
        HOMEButton                    matlab.ui.control.Button
        Q6Slider                      matlab.ui.control.Slider
        Q6SliderLabel                 matlab.ui.control.Label
        EditField_6                   matlab.ui.control.NumericEditField
        Q5Slider                      matlab.ui.control.Slider
        Q5SliderLabel                 matlab.ui.control.Label
        EditField_5                   matlab.ui.control.NumericEditField
        Q4Slider                      matlab.ui.control.Slider
        Q4SliderLabel                 matlab.ui.control.Label
        EditField_4                   matlab.ui.control.NumericEditField
        Q3Slider                      matlab.ui.control.Slider
        Q3SliderLabel                 matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        Q2Slider                      matlab.ui.control.Slider
        Q2SliderLabel                 matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        Q1Slider                      matlab.ui.control.Slider
        Q1SliderLabel                 matlab.ui.control.Label
        ForwardKinematicTab           matlab.ui.container.Tab
        StandardHomogeneousTransformationMatrixButton  matlab.ui.control.Button
        ref_2                         matlab.ui.control.Label
        ref                           matlab.ui.control.Label
        DHTablo                       matlab.ui.control.Table
        allnoneCheckBox               matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_1                    matlab.ui.control.CheckBox
        BaseCheckBox                  matlab.ui.control.CheckBox
        InverseKinematicTab           matlab.ui.container.Tab
        TransformMatrix               matlab.ui.control.Table
        ResetButton                   matlab.ui.control.Button
        GOButton                      matlab.ui.control.Button
        PzSpinner                     matlab.ui.control.Spinner
        PzSpinnerLabel                matlab.ui.control.Label
        PySpinner                     matlab.ui.control.Spinner
        PySpinnerLabel                matlab.ui.control.Label
        PxSpinner                     matlab.ui.control.Spinner
        PxSpinnerLabel                matlab.ui.control.Label
        WorkspaceTab                  matlab.ui.container.Tab
        PointsSlider                  matlab.ui.control.Slider
        PointsValueSliderLabel        matlab.ui.control.Label
        ShowWorkspaceButton           matlab.ui.control.Button
    end

    
properties (Access = private)
    Robot       % SerialLink robot nesnesi
    %q_current = zeros(1,6);  % Açı vektörü
    q_current     % 1x6 eklem açıları (radyan)
    demoRunning = false;  % Demo aktif mi?
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadRobotButton
        function LoadRobotButtonPushed(app, event)
% Robot modelini oluştur ve kaydet
app.Robot = KR160_robot_model();

% Başlangıç konumunu sıfırla
app.q_current = deg2rad([0 -90 90 0 0 0]);

% Yeni figür penceresi aç (GÜNCELLENDİ)
f = findobj('Type','figure','Name','KR160 Robot Simülasyonu');
if isempty(f) || ~ishandle(f(1))
    f = figure('Name','KR160 Robot Simülasyonu','Color','w');
else
    figure(f(1)); clf;
end

% Robotu çiz
app.Robot.plot(app.q_current, ...
    'workspace', [-2 2 -2 2 0 2.5], ...
    'noarrow', 'floorlevel', 0, 'scale', 0.7);

% Görünüm ve başlık
view(150, 30);
title('KR160\_R1570\_nano Robotu','FontSize',12);
xlabel('X'); ylabel('Y'); zlabel('Z');

% Eksenleri göster
updateAxisVisibility(app);
end

function updateEndEffectorPosition(app)
    pos = app.Robot.fkine(app.q_current).t;
    app.PositionField.Value = sprintf('X=%.1f Y=%.1f Z=%.1f', pos(1), pos(2), pos(3));
end

function updateAxisVisibility(app)
    q = app.q_current;

    % Çizim ayarları
    plot_opts = {
        'workspace', [-1.5 1.5 -1.5 1.5 0 2], ...
        'scale', 0.7, ...
        'noarrow', ...
        'floorlevel', 0 ...
    };

    % Figürü bul veya oluştur
    f = findobj('Type', 'figure', 'Name', 'KR160 Robot Simülasyonu');
    if isempty(f) || ~ishandle(f(1))
        f = figure('Name', 'KR160 Robot Simülasyonu', 'Color', 'w');
    else
        f = f(1);
    end

    % Aktif figürü seç ve temizle
    figure(f);
    clf(f);

    % Robotu çiz
    app.Robot.plot(q, plot_opts{:});
    view(150, 30);
    title('Forward Kinematic - Eksen Görselleştirme', 'FontSize', 12);
    xlabel('X'); ylabel('Y'); zlabel('Z');

    hold on;

    % Eksenleri çiz
    for i = 0:6
        switch i
            case 0, draw = app.BaseCheckBox.Value;
            case 1, draw = app.CheckBox_1.Value;
            case 2, draw = app.CheckBox_2.Value;
            case 3, draw = app.CheckBox_3.Value;
            case 4, draw = app.CheckBox_4.Value;
            case 5, draw = app.CheckBox_5.Value;
            case 6, draw = app.CheckBox_6.Value;
        end

        if draw
            % 🔁 Her link için doğru dönüşüm matrisi hesapla
            if i == 0
                T = app.Robot.base;  % Base
            else
                T = app.Robot.base;
                for k = 1:i
                    T = T * app.Robot.A(k, q);
                end
            end

            trplot(T, 'frame', sprintf('%d', i), 'length', 0.6, 'rgb', 'thick', 4);
        end
    end

    hold off;
end
function updateTargetMarker(app)
    % Spinnerlardan pozisyonu al
    px = app.PxSpinner.Value;
    py = app.PySpinner.Value;
    pz = app.PzSpinner.Value;

    % Figürü bul ve aktif et
    f = findobj('Type','figure','Name','KR160 Robot Simülasyonu');
    if isempty(f)
        return;
    end
    figure(f);

    % Mevcut marker varsa sil
    delete(findobj(gca, 'Tag', 'TargetMarker'));

    % Yeni hedef noktasını çiz (kırmızı nokta)
    hold on;
    plot3(px, py, pz, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r', 'Tag', 'TargetMarker');
    hold off;
end
function updateDHTable(app)
    % Açılar derecede alınacak
    q_deg = rad2deg(app.q_current);

    % Robotun sabit DH parametreleri (senin robota göre uyarladım)
    d = [0.75 0 0 1.2 0 0.2];
    a = [0 0.35 1 0 0 0];
    alpha = [-90 0 90 -90 90 0];

    % 6x4 tabloyu doldur
    dh_data = zeros(6,4);
    for i = 1:6
        dh_data(i,:) = [q_deg(i), d(i), a(i), alpha(i)];
    end

    % GUI'deki tabloya aktar
    app.DHTablo.Data = dh_data;
end
function updateTransformMatrix(app, T)
    % Hücre isimleri (etiketler)
    labels = {
        'r₁₁', 'r₁₂', 'r₁₃', 'pₓ';
        'r₂₁', 'r₂₂', 'r₂₃', 'pᵧ';
        'r₃₁', 'r₃₂', 'r₃₃', 'p𝓏';
        '',    '',    '',    ''
    };

    % Değerleri yazı haline çevir
    data = strings(4, 4);
    for i = 1:3
        for j = 1:4
            data(i, j) = sprintf('%s = %.4f', labels{i,j}, T(i,j));
        end
    end
    data(4, :) = ["0", "0", "0", "1"];

    % Tabloya yaz
    app.TransformMatrix.Data = data;
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
 % Q1 değerini al
    deg = app.Q1Slider.Value;
    
    % Derece → Radyan dönüşümü
    app.q_current(1) = deg2rad(deg);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Değeri kutuda göster
    app.EditField.Value = round(deg,1);

    % Uç efektör pozisyonunu güncelle
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
 % Q1 değerini al
    deg = app.Q2Slider.Value;
    
    % Derece → Radyan dönüşümü
    app.q_current(2) = deg2rad(deg);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Değeri kutuda göster
    app.EditField_2.Value = round(deg,1);

    % Uç efektör pozisyonunu güncelle
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
 % Q1 değerini al
    deg = app.Q3Slider.Value;
    
    % Derece → Radyan dönüşümü
    app.q_current(3) = deg2rad(deg);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Değeri kutuda göster
    app.EditField_3.Value = round(deg,1);

    % Uç efektör pozisyonunu güncelle
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
 % Q1 değerini al
    deg = app.Q4Slider.Value;
    
    % Derece → Radyan dönüşümü
    app.q_current(4) = deg2rad(deg);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Değeri kutuda göster
    app.EditField_4.Value = round(deg,1);

    % Uç efektör pozisyonunu güncelle
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
  % Q1 değerini al
    deg = app.Q5Slider.Value;
    
    % Derece → Radyan dönüşümü
    app.q_current(5) = deg2rad(deg);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Değeri kutuda göster
    app.EditField_5.Value = round(deg,1);

    % Uç efektör pozisyonunu güncelle
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Value changed function: Q6Slider
        function Q6SliderValueChanged(app, event)
   % Q1 değerini al
    deg = app.Q6Slider.Value;
    
    % Derece → Radyan dönüşümü
    app.q_current(6) = deg2rad(deg);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Değeri kutuda göster
    app.EditField_6.Value = round(deg,1);

    % Uç efektör pozisyonunu güncelle
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
             % Başlangıç konumunu ata
    app.q_current = deg2rad([0 -90 90 0 0 0]);

    % Robotu çiz
    app.Robot.plot(app.q_current);

    % Slider ve edit alanlarını güncelle
    app.Q1Slider.Value = rad2deg(app.q_current(1));
    app.EditField.Value = round(rad2deg(app.q_current(1)),1);

    app.Q2Slider.Value = rad2deg(app.q_current(2));
    app.EditField_2.Value = round(rad2deg(app.q_current(2)),1);

    app.Q3Slider.Value = rad2deg(app.q_current(3));
    app.EditField_3.Value = round(rad2deg(app.q_current(3)),1);

    app.Q4Slider.Value = rad2deg(app.q_current(4));
    app.EditField_4.Value = round(rad2deg(app.q_current(4)),1);

    app.Q5Slider.Value = rad2deg(app.q_current(5));
    app.EditField_5.Value = round(rad2deg(app.q_current(5)),1);

    app.Q6Slider.Value = rad2deg(app.q_current(6));
    app.EditField_6.Value = round(rad2deg(app.q_current(6)),1);

    % Uç efektör pozisyonunu göster
    updateEndEffectorPosition(app);

    updateDHTable(app);
        end

        % Button pushed function: DEMOButton
        function DEMOButtonPushed(app, event)
    if app.demoRunning
        % Demo zaten çalışıyor, tekrar tıklandı → durdur
        app.demoRunning = false;
        app.DEMOButton.Text = "Demo";
        return;
    end

    app.demoRunning = true;
    app.DEMOButton.Text = "Durdur";

    steps = 12;
    pauseTime = 0.02;
    q_home = deg2rad([0 -90 90 0 0 0]);
    app.q_current = q_home;

    sliders = [app.Q1Slider, app.Q2Slider, app.Q3Slider, app.Q4Slider, app.Q5Slider, app.Q6Slider];
    fields  = [app.EditField, app.EditField_2, app.EditField_3, app.EditField_4, app.EditField_5, app.EditField_6];

    for i = 1:6
        if ~app.demoRunning
            break;
        end

        q_start = app.q_current;
        q_min = q_start; q_min(i) = app.Robot.links(i).qlim(1);
        q_max = q_start; q_max(i) = app.Robot.links(i).qlim(2);

        traj1 = jtraj(q_start, q_min, steps);
        traj2 = jtraj(q_min(end,:), q_max, steps);
        traj3 = jtraj(q_max(end,:), q_home, steps);
        traj = [traj1; traj2; traj3];

        for j = 1:size(traj,1)
            if ~app.demoRunning
                break;
            end

            app.q_current = traj(j,:);
            app.Robot.plot(app.q_current);

            deg = rad2deg(app.q_current(i));
            deg = max(min(deg, sliders(i).Limits(2)), sliders(i).Limits(1));
            sliders(i).Value = deg;
            fields(i).Value = round(deg,1);

            updateEndEffectorPosition(app);
            pause(pauseTime);
        end
    end

    app.demoRunning = false;
    app.DEMOButton.Text = "Demo";

    updateDHTable(app);

        end

        % Value changed function: allnoneCheckBox
        function allnoneCheckBoxValueChanged(app, event)
 newState = app.allnoneCheckBox.Value;  % true veya false

    % Diğer tüm checkbox’lara bu durumu uygula
    app.BaseCheckBox.Value = newState;
    app.CheckBox_1.Value = newState;
    app.CheckBox_2.Value = newState;
    app.CheckBox_3.Value = newState;
    app.CheckBox_4.Value = newState;
    app.CheckBox_5.Value = newState;
    app.CheckBox_6.Value = newState;

    % Robotu tekrar çiz (istersen)
    updateAxisVisibility(app);  
        end

        % Value changed function: BaseCheckBox
        function BaseCheckBoxValueChanged(app, event)
            value = app.BaseCheckBox.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            updateAxisVisibility(app);
        end

        % Value changed function: PxSpinner
        function PxSpinnerValueChanged(app, event)
            value = app.PxSpinner.Value;
updateTargetMarker(app);
            
        end

        % Value changed function: PySpinner
        function PySpinnerValueChanged(app, event)
            value = app.PySpinner.Value;
            updateTargetMarker(app);
        end

        % Value changed function: PzSpinner
        function PzSpinnerValueChanged(app, event)
            value = app.PzSpinner.Value;
            updateTargetMarker(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
% Spinnerlardan hedef pozisyonu oku
px = app.PxSpinner.Value;
py = app.PySpinner.Value;
pz = app.PzSpinner.Value;

% Hedef pozisyon matrisi (sabit yönelimle)
T_target = transl([px py pz]);

% Ters kinematik çözümü (sadece pozisyon için, sabit yönelim)
q_target = app.Robot.ikine(T_target, 'mask', [1 1 1 0 0 0]);

% Hareket interpolasyonu (akıcı geçiş)
steps = 30;
traj = jtraj(app.q_current, q_target, steps);

for i = 1:steps
    app.q_current = traj(i,:);
    app.Robot.plot(app.q_current);
    updateEndEffectorPosition(app);
    pause(0.01);
end

% 🔁 Dönüşüm matrisini tabloda göster
updateTransformMatrix(app, T_target);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Ev pozisyonu
    q_home = deg2rad([0 -90 90 0 0 0]);
    app.q_current = q_home;
    app.Robot.plot(app.q_current);
    updateEndEffectorPosition(app);

    % Spinner'ları sıfırla
    app.PxSpinner.Value = 0;
    app.PySpinner.Value = 0;
    app.PzSpinner.Value = 1;

    % Kırmızı hedef noktasını güncelle
    hold on;
    plot3(0, 0, 1, 'ro', 'MarkerSize', 10, 'LineWidth', 2);
    hold off;
        end

        % Button pushed function: ShowWorkspaceButton
        function ShowWorkspaceButtonPushed(app, event)
    % Slider’dan nokta sayısını al
    n = round(app.PointsSlider.Value);  % 🔴 PointsSlider GUI'de tanımlı olmalı
    points = zeros(n,3);

    for i = 1:n
        q_rand = (rand(1,6)-0.5)*2*pi;  % -pi ile +pi arası rastgele açı
        if app.Robot.isspherical || true
            T = app.Robot.fkine(q_rand);
            points(i,:) = T.t';
        end
    end

    % Figür penceresi
    f = findobj('Type','figure','Name','KR160 Robot Simülasyonu');
    if isempty(f)
        f = figure('Name','KR160 Robot Simülasyonu','Color','w');
    else
        f = f(1);
    end

    figure(f); clf(f); hold on;

    % Robotu çiz
    app.Robot.plot(app.q_current, ...
        'workspace', [-2 2 -2 2 0 2.5], ...
        'noarrow', 'floorlevel', 0, 'scale', 0.7);
    
    % Çalışma alanını çiz
    scatter3(points(:,1), points(:,2), points(:,3), 10, 'filled', 'r');

    % Eksen etiketleri ve görünüm
    title('Workspace (Reachable Points)');
    xlabel('X'); ylabel('Y'); zlabel('Z');
    view(135, 25);
    grid on;
        end

        % Size changed function: WorkspaceTab
        function WorkspaceTabSizeChanged(app, event)
            position = app.WorkspaceTab.Position;
app.PointsValue.Value = round(app.PointsSlider.Value);
            
        end

        % Button pushed function: 
        % StandardHomogeneousTransformationMatrixButton
        function StandardHomogeneousTransformationMatrixButtonPushed(app, event)
            figure('Name', 'İleri Kinematik Görseli');
    imshow('forward_kinematics.png');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.LoadRobotButton = uibutton(app.MainTab, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            app.Image = uiimage(app.MainTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Mehmet_BARILabel = uilabel(app.MainTab);
            app.Mehmet_BARILabel.Text = '222808064_Mehmet_BARIŞ';
            app.Mert_KARARLabel = uilabel(app.MainTab);
            app.Mert_KARARLabel.Text = '222808054_Mert_KARAR';
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'KUKA.png');
            app.KUKAKR160R1570NANOROBOTLabel = uilabel(app.MainTab);
            app.KUKAKR160R1570NANOROBOTLabel.Text = 'KUKA KR 160 R1570 NANO ROBOT';
            app.ControlPanelTab = uitab(app.TabGroup);
            app.ControlPanelTab.Title = 'Control Panel';
            app.Q1SliderLabel = uilabel(app.ControlPanelTab);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.ControlPanelTab);
            app.Q1Slider.Limits = [-185 185];
            app.Q1Slider.MajorTicks = [-185 0 185];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q1Slider.MinorTicks = [-185 0 185];
            app.EditField = uieditfield(app.ControlPanelTab, 'numeric');
            app.EditField_2 = uieditfield(app.ControlPanelTab, 'numeric');
            app.Q2SliderLabel = uilabel(app.ControlPanelTab);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.ControlPanelTab);
            app.Q2Slider.Limits = [-145 45];
            app.Q2Slider.MajorTicks = [-145 -90 0 45];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q2Slider.MinorTicks = [-145 -90 0 45];
            app.Q2Slider.Value = -90;
            app.EditField_3 = uieditfield(app.ControlPanelTab, 'numeric');
            app.Q3SliderLabel = uilabel(app.ControlPanelTab);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.ControlPanelTab);
            app.Q3Slider.Limits = [-130 145];
            app.Q3Slider.MajorTicks = [-130 0 90 145];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q3Slider.MinorTicks = [-130 0 90 145];
            app.Q3Slider.Value = 90;
            app.EditField_4 = uieditfield(app.ControlPanelTab, 'numeric');
            app.Q4SliderLabel = uilabel(app.ControlPanelTab);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.ControlPanelTab);
            app.Q4Slider.Limits = [-179 179];
            app.Q4Slider.MajorTicks = [-179 0 179];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q4Slider.MinorTicks = [-179 0 179];
            app.EditField_5 = uieditfield(app.ControlPanelTab, 'numeric');
            app.Q5SliderLabel = uilabel(app.ControlPanelTab);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.ControlPanelTab);
            app.Q5Slider.Limits = [-120 120];
            app.Q5Slider.MajorTicks = [-120 0 120];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.Q5Slider.MinorTicks = [-120 0 120];
            app.EditField_6 = uieditfield(app.ControlPanelTab, 'numeric');
            app.Q6SliderLabel = uilabel(app.ControlPanelTab);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.ControlPanelTab);
            app.Q6Slider.Limits = [-179 179];
            app.Q6Slider.MajorTicks = [-179 0 179];
            app.Q6Slider.ValueChangedFcn = createCallbackFcn(app, @Q6SliderValueChanged, true);
            app.Q6Slider.MinorTicks = [-179 0 179];
            app.HOMEButton = uibutton(app.ControlPanelTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.DEMOButton = uibutton(app.ControlPanelTab, 'push');
            app.DEMOButton.ButtonPushedFcn = createCallbackFcn(app, @DEMOButtonPushed, true);
            app.DEMOButton.Text = 'DEMO';
            app.POSITIONLabel = uilabel(app.ControlPanelTab);
            app.POSITIONLabel.Text = 'POSITION';
            app.PositionField = uieditfield(app.ControlPanelTab, 'text');
            app.PositionField.Editable = 'off';
            app.ForwardKinematicTab = uitab(app.TabGroup);
            app.ForwardKinematicTab.Title = 'Forward Kinematic';
            app.BaseCheckBox = uicheckbox(app.ForwardKinematicTab);
            app.BaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaseCheckBoxValueChanged, true);
            app.BaseCheckBox.Text = '0 (Base)';
            app.CheckBox_1 = uicheckbox(app.ForwardKinematicTab);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.ForwardKinematicTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.ForwardKinematicTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.ForwardKinematicTab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.ForwardKinematicTab);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '5';
            app.CheckBox_6 = uicheckbox(app.ForwardKinematicTab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '6';
            app.allnoneCheckBox = uicheckbox(app.ForwardKinematicTab);
            app.allnoneCheckBox.ValueChangedFcn = createCallbackFcn(app, @allnoneCheckBoxValueChanged, true);
            app.allnoneCheckBox.Text = 'all/none';
            app.DHTablo = uitable(app.ForwardKinematicTab);
            app.DHTablo.ColumnName = {'                                       θ (deg)'; 'd'; 'a'; 'α (deg)'};
            app.DHTablo.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.ref = uilabel(app.ForwardKinematicTab);
            app.ref.Text = 'Show reference frames';
            app.ref_2 = uilabel(app.ForwardKinematicTab);
            app.ref_2.Text = 'Denavit-Hartenberg Parameters';
            app.StandardHomogeneousTransformationMatrixButton = uibutton(app.ForwardKinematicTab, 'push');
            app.StandardHomogeneousTransformationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @StandardHomogeneousTransformationMatrixButtonPushed, true);
            app.StandardHomogeneousTransformationMatrixButton.Text = 'Standard Homogeneous Transformation Matrix';
            app.InverseKinematicTab = uitab(app.TabGroup);
            app.InverseKinematicTab.Title = 'Inverse Kinematic';
            app.PxSpinnerLabel = uilabel(app.InverseKinematicTab);
            app.PxSpinnerLabel.Text = 'Px';
            app.PxSpinner = uispinner(app.InverseKinematicTab);
            app.PxSpinner.Step = 0.1;
            app.PxSpinner.ValueChangedFcn = createCallbackFcn(app, @PxSpinnerValueChanged, true);
            app.PxSpinner.Editable = 'off';
            app.PySpinnerLabel = uilabel(app.InverseKinematicTab);
            app.PySpinnerLabel.Text = 'Py';
            app.PySpinner = uispinner(app.InverseKinematicTab);
            app.PySpinner.Step = 0.1;
            app.PySpinner.ValueChangedFcn = createCallbackFcn(app, @PySpinnerValueChanged, true);
            app.PySpinner.Editable = 'off';
            app.PzSpinnerLabel = uilabel(app.InverseKinematicTab);
            app.PzSpinnerLabel.Text = 'Pz';
            app.PzSpinner = uispinner(app.InverseKinematicTab);
            app.PzSpinner.Step = 0.1;
            app.PzSpinner.ValueChangedFcn = createCallbackFcn(app, @PzSpinnerValueChanged, true);
            app.PzSpinner.Editable = 'off';
            app.GOButton = uibutton(app.InverseKinematicTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'Go';
            app.ResetButton = uibutton(app.InverseKinematicTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TransformMatrix = uitable(app.InverseKinematicTab);
            app.TransformMatrix.ColumnName = '';
            app.TransformMatrix.RowName = {};
            app.TransformMatrix.Tag = 'T =';
            app.WorkspaceTab = uitab(app.TabGroup);
            app.WorkspaceTab.SizeChangedFcn = createCallbackFcn(app, @WorkspaceTabSizeChanged, true);
            app.WorkspaceTab.Title = 'Workspace';
            app.ShowWorkspaceButton = uibutton(app.WorkspaceTab, 'push');
            app.ShowWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowWorkspaceButtonPushed, true);
            app.ShowWorkspaceButton.Text = 'Show Workspace';
            app.PointsValueSliderLabel = uilabel(app.WorkspaceTab);
            app.PointsValueSliderLabel.Text = {'Points'; 'Value'};
            app.PointsSlider = uislider(app.WorkspaceTab);
            app.PointsSlider.Limits = [0 1000];
            app.PointsSlider.MajorTicks = [0 50 250 500 750 1000];
            app.PointsSlider.MinorTicks = [0 50 250 500 750 1000];
            app.PointsSlider.Step = 10;
            app.PointsSlider.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KR160_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end